classdef AppPrac02LeonNavarro < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SenalesentiempodiscretoUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        IngresalafuncionftLabel        matlab.ui.control.Label
        IngresalafuncionftEditField    matlab.ui.control.EditField
        MoverGraficaaPanel             matlab.ui.container.Panel
        DerchanunidadesEditFieldLabel  matlab.ui.control.Label
        DerchanunidadesEditField       matlab.ui.control.NumericEditField
        IzquierdanunidadesEditFieldLabel  matlab.ui.control.Label
        IzquierdanunidadesEditField    matlab.ui.control.NumericEditField
        ArribanunidadesEditFieldLabel  matlab.ui.control.Label
        ArribanunidadesEditField       matlab.ui.control.NumericEditField
        AbajonunidadesEditFieldLabel   matlab.ui.control.Label
        AbajonunidadesEditField        matlab.ui.control.NumericEditField
        EscalarnunidadesPanel          matlab.ui.container.Panel
        DilatarverticalmenteEditFieldLabel  matlab.ui.control.Label
        DilatarverticalmenteEditField  matlab.ui.control.NumericEditField
        ComprimirverticalmenteEditFieldLabel  matlab.ui.control.Label
        ComprimirverticalmenteEditField  matlab.ui.control.NumericEditField
        DilatarhorizontalmenteEditFieldLabel  matlab.ui.control.Label
        DilatarhorizontalmenteEditField  matlab.ui.control.NumericEditField
        ComprimehorizontalmenteEditFieldLabel  matlab.ui.control.Label
        ComprimehorizontalmenteEditField  matlab.ui.control.NumericEditField
        GraficarButton                 matlab.ui.control.Button
        GrficaPanel                    matlab.ui.container.Panel
        xEditField_2                   matlab.ui.control.NumericEditField
        xLabel                         matlab.ui.control.Label
        xEditField                     matlab.ui.control.NumericEditField
        yLabel                         matlab.ui.control.Label
        yEditField                     matlab.ui.control.NumericEditField
        yEditField_2                   matlab.ui.control.NumericEditField
        SealesentiempoDiscretoLabel    matlab.ui.control.Label
        AyudaButton                    matlab.ui.control.Button
        InstruccionesTextAreaLabel     matlab.ui.control.Label
        InstruccionesTextArea          matlab.ui.control.TextArea
        LimpiarButton                  matlab.ui.control.Button
        SealoriginalLabel              matlab.ui.control.Label
        Original                       matlab.ui.control.Label
        SealtransformadaLabel          matlab.ui.control.Label
        conv                           matlab.ui.control.Label
        DaclickaquiparaaprenderausarlaappLabel  matlab.ui.control.Label
        IndicaelintervaloparaelquequieresquesegrafiquelafuncinLabel  matlab.ui.control.Label
        IndicaelintervaloparaelquequieresquesegrafiquelafuncinLabel_2  matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        ytUIAxes                       matlab.ui.control.UIAxes
        ytAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SenalesentiempodiscretoUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {863, 863};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {596, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function
        function MonthlyPaymentButtonPushed(app, event)
                       
            % Calculate the monthly payment
           
            amount = app.LoanAmountEditField.Value ;
            rate = app.InterestRateEditField.Value/12/100 ;
            nper = 12*app.LoanPeriodYearsEditField.Value ;
            payment = (amount*rate)/(1-(1+rate)^-nper);
            app.MonthlyPaymentEditField.Value = payment;
            
            % pre allocating and initializing variables 
            interest = zeros(1,nper);
            principal = zeros(1,nper);
            balance = zeros (1,nper);
            
            balance(1) = amount;
            
            % Calculate the principal and interest over time
            for i = 1:nper
                interest(i)  = balance(i)*rate ;
                principal(i) = payment - interest(i) ;
                balance(i+1) = balance(i) - principal(i) ;
            end
            
%           
            
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
        
            a=app.DerchanunidadesEditField.Value;
            b=app.IzquierdanunidadesEditField.Value;
            c=app.ArribanunidadesEditField.Value;
            d=app.AbajonunidadesEditField.Value;
            e=app.DilatarverticalmenteEditField.Value;
            f=app.ComprimirverticalmenteEditField.Value;
            g=app.DilatarhorizontalmenteEditField.Value;
            h=app.ComprimehorizontalmenteEditField.Value;
            if a>b
               th=num2str(-1*abs(a-b))
            else 
                th=num2str(1*abs(a-b))
            end
            if c>d
               tv=num2str(abs(c-d))
            else 
               tv=num2str(-1*abs(c-d))
            end            
            dv=num2str(e)
            cv=num2str(f)
            dh=num2str(g)
            ch=num2str(h)
            f=app.IngresalafuncionftEditField.Value;
            
            fh=replace(f,'t',strcat('(','t','+',th,')'))  %Desplazamos primero verticalmente
            fh=replace(fh,'t',strcat('(',ch,'/',dh,'*','t',')'))
            fv=strcat(dv,'/',cv,'*(',fh,')')
            fv=strcat(fv,'+',tv)
            y=@(t) eval(fv);
            yo=@(t) eval(f);
            x1=app.xEditField_2.Value;
            x2=app.xEditField.Value;
            t1=x1:1:x2;
            t2=g*x1:g:g*x2;
            %plot(app.ytUIAxes,t,y(t),'r')
            stem(app.ytUIAxes,t2,y(t2),'filled')
            stem(app.ytAxes,t1,yo(t1),'filled')
            set(app.ytUIAxes,'XAxisLocation','origin','YAxisLocation','origin');
            y1=app.yEditField_2.Value;
            y2=app.yEditField.Value;
            ylim(app.ytUIAxes,[y1 y2]);
            xlim(app.ytUIAxes,[x1 x2])
            grid(app.ytUIAxes,'on');
            
            %plot(app.ytAxes,t,yo(t),'g')
            set(app.ytAxes,'XAxisLocation','origin','YAxisLocation','origin');
            y1=app.yEditField_2.Value;
            y2=app.yEditField.Value;
            ylim(app.ytAxes,[y1 y2]);
            xlim(app.ytAxes,[x1 x2])
            grid(app.ytAxes,'on');
            
            set(app.Original,'Text',strcat("f(t)=",f))
            set(app.conv,'Text',fv)


        end

        % Button pushed function: AyudaButton
        function AyudaButtonPushed(app, event)
            set(app.InstruccionesTextArea,'Visible','on')
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            set(app.xEditField_2,'Value',-10)
             set(app.xEditField,'Value',10)
             
             set(app.yEditField_2,'Value',-10)
             set(app.yEditField,'Value',10)
             set(app.yEditField,'Value',10)
            set(app.DerchanunidadesEditField,'Value',0)
            set(app.IzquierdanunidadesEditField,'Value',0)
            set(app.ArribanunidadesEditField,'Value',0)
            
            set(app.IngresalafuncionftEditField,'Value','')
            
            set(app.DilatarverticalmenteEditField,'Value',1)
             set(app.ComprimirverticalmenteEditField,'Value',1)
              set(app.DilatarhorizontalmenteEditField,'Value',1)
               set(app.ComprimehorizontalmenteEditField,'Value',1)
              
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SenalesentiempodiscretoUIFigure = uifigure('Visible', 'off');
            app.SenalesentiempodiscretoUIFigure.Name = 'Señales en tiempo discreto';
            app.SenalesentiempodiscretoUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SenalesentiempodiscretoUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.IngresalafuncionftLabel = uilabel(app.LeftPanel);
            app.IngresalafuncionftLabel.Text = 'Ingresa la funcion f(t)=';
            app.IngresalafuncionftEditField = uieditfield(app.LeftPanel, 'text');
            app.MoverGraficaaPanel = uipanel(app.LeftPanel);
            app.MoverGraficaaPanel.Title = 'Mover Grafica a ';
            app.DerchanunidadesEditFieldLabel = uilabel(app.MoverGraficaaPanel);
            app.DerchanunidadesEditFieldLabel.Text = 'Dercha n unidades';
            app.DerchanunidadesEditField = uieditfield(app.MoverGraficaaPanel, 'numeric');
            app.IzquierdanunidadesEditFieldLabel = uilabel(app.MoverGraficaaPanel);
            app.IzquierdanunidadesEditFieldLabel.Text = 'Izquierda n unidades';
            app.IzquierdanunidadesEditField = uieditfield(app.MoverGraficaaPanel, 'numeric');
            app.ArribanunidadesEditFieldLabel = uilabel(app.MoverGraficaaPanel);
            app.ArribanunidadesEditFieldLabel.Text = 'Arriba n unidades';
            app.ArribanunidadesEditField = uieditfield(app.MoverGraficaaPanel, 'numeric');
            app.AbajonunidadesEditFieldLabel = uilabel(app.MoverGraficaaPanel);
            app.AbajonunidadesEditFieldLabel.Text = 'Abajo n unidades';
            app.AbajonunidadesEditField = uieditfield(app.MoverGraficaaPanel, 'numeric');
            app.EscalarnunidadesPanel = uipanel(app.LeftPanel);
            app.EscalarnunidadesPanel.Title = 'Escalar n unidades';
            app.DilatarverticalmenteEditFieldLabel = uilabel(app.EscalarnunidadesPanel);
            app.DilatarverticalmenteEditFieldLabel.Text = 'Dilatar verticalmente';
            app.DilatarverticalmenteEditField = uieditfield(app.EscalarnunidadesPanel, 'numeric');
            app.DilatarverticalmenteEditField.Value = 1;
            app.ComprimirverticalmenteEditFieldLabel = uilabel(app.EscalarnunidadesPanel);
            app.ComprimirverticalmenteEditFieldLabel.Text = 'Comprimir verticalmente';
            app.ComprimirverticalmenteEditField = uieditfield(app.EscalarnunidadesPanel, 'numeric');
            app.ComprimirverticalmenteEditField.Value = 1;
            app.DilatarhorizontalmenteEditFieldLabel = uilabel(app.EscalarnunidadesPanel);
            app.DilatarhorizontalmenteEditFieldLabel.Text = 'Dilatar horizontalmente';
            app.DilatarhorizontalmenteEditField = uieditfield(app.EscalarnunidadesPanel, 'numeric');
            app.DilatarhorizontalmenteEditField.Value = 1;
            app.ComprimehorizontalmenteEditFieldLabel = uilabel(app.EscalarnunidadesPanel);
            app.ComprimehorizontalmenteEditFieldLabel.Text = 'Comprime horizontalmente';
            app.ComprimehorizontalmenteEditField = uieditfield(app.EscalarnunidadesPanel, 'numeric');
            app.ComprimehorizontalmenteEditField.Value = 1;
            app.GraficarButton = uibutton(app.LeftPanel, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.GrficaPanel = uipanel(app.LeftPanel);
            app.GrficaPanel.Title = 'Gráfica';
            app.xEditField_2 = uieditfield(app.GrficaPanel, 'numeric');
            app.xEditField_2.Value = -10;
            app.xLabel = uilabel(app.GrficaPanel);
            app.xLabel.Text = '≤x≤';
            app.xEditField = uieditfield(app.GrficaPanel, 'numeric');
            app.xEditField.Value = 10;
            app.yLabel = uilabel(app.GrficaPanel);
            app.yLabel.Text = '≤y≤';
            app.yEditField = uieditfield(app.GrficaPanel, 'numeric');
            app.yEditField.Value = 10;
            app.yEditField_2 = uieditfield(app.GrficaPanel, 'numeric');
            app.yEditField_2.Value = -10;
            app.SealesentiempoDiscretoLabel = uilabel(app.LeftPanel);
            app.SealesentiempoDiscretoLabel.Text = 'Señales en tiempo Discreto';
            app.AyudaButton = uibutton(app.LeftPanel, 'push');
            app.AyudaButton.ButtonPushedFcn = createCallbackFcn(app, @AyudaButtonPushed, true);
            app.AyudaButton.Text = 'Ayuda';
            app.InstruccionesTextAreaLabel = uilabel(app.LeftPanel);
            app.InstruccionesTextAreaLabel.Visible = 'off';
            app.InstruccionesTextAreaLabel.Text = 'Instrucciones';
            app.InstruccionesTextArea = uitextarea(app.LeftPanel);
            app.InstruccionesTextArea.Editable = 'off';
            app.InstruccionesTextArea.Visible = 'off';
            app.InstruccionesTextArea.Value = {'1.Se debe ingresar la señal con formato de Matlab con la variable t'; 'Ejemplo'; 'a)t.^2+t    b)cos(t)  c)sin(t)  d)3*t+2'; '2.El valor predeterminados de las transiciones (izquierda,derecha,arriba,abajo) es 0 mientras que el de los escalamientos es 1, así que si no desea aplicar una transformación dejar en los valores predeterminados . Si desea que los valores se restauren automáticamente presione en limpiar'; '3.Ingresar los valores de las unidades de las transformaciones  con números naturales'; '4.Ingresar los intervalos de la gráfica con el primer valor diferente y menor que el segundo'; '5.Oprimir en el botón graficar'; 'Nota si desea reflejar con respecto al eje y  poner un valor de -1 en el escalamiento horizontal se compresion o dilatacion '; 'Si se quiere invertir con respecto a y poner un valor engativo en el escalamiento vertical sea compresion o dilatacion'; ''};
            app.LimpiarButton = uibutton(app.LeftPanel, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.SealoriginalLabel = uilabel(app.LeftPanel);
            app.SealoriginalLabel.Text = 'Señal original';
            app.Original = uilabel(app.LeftPanel);
            app.Original.Text = '';
            app.SealtransformadaLabel = uilabel(app.LeftPanel);
            app.SealtransformadaLabel.Text = 'Señal transformada';
            app.conv = uilabel(app.LeftPanel);
            app.conv.Text = '';
            app.DaclickaquiparaaprenderausarlaappLabel = uilabel(app.LeftPanel);
            app.DaclickaquiparaaprenderausarlaappLabel.Text = '<------ Da click aqui para aprender a usar la app :)';
            app.IndicaelintervaloparaelquequieresquesegrafiquelafuncinLabel = uilabel(app.LeftPanel);
            app.IndicaelintervaloparaelquequieresquesegrafiquelafuncinLabel.Text = {'<----- Indica el intervalo para el que'; 'quieres que se evalúe y grafique la función'};
            app.IndicaelintervaloparaelquequieresquesegrafiquelafuncinLabel_2 = uilabel(app.LeftPanel);
            app.IndicaelintervaloparaelquequieresquesegrafiquelafuncinLabel_2.Text = {'<----- Intervalo de y que se visualiza en la'; 'gráfica'};
            app.RightPanel = uipanel(app.GridLayout);
            app.ytUIAxes = uiaxes(app.RightPanel);
            title(app.ytUIAxes, 'Grafica modificada')
            xlabel(app.ytUIAxes, 't')
            ylabel(app.ytUIAxes, 'y(t)')
            app.ytAxes = uiaxes(app.RightPanel);
            title(app.ytAxes, 'Grafica original')
            xlabel(app.ytAxes, 't')
            ylabel(app.ytAxes, 'y(t)')
            % Show the figure after all components are created
            app.SenalesentiempodiscretoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppPrac02LeonNavarro

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SenalesentiempodiscretoUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SenalesentiempodiscretoUIFigure)
        end
    end
end